classdef TiresMF < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        ComportamientodedinamicalongitudinalylateralUtilizandoMF62Label  matlab.ui.control.Label
        FzNLabel                        matlab.ui.control.Label
        Fz                              matlab.ui.control.NumericEditField
        VpromkmhLabel                   matlab.ui.control.Label
        Vx                              matlab.ui.control.NumericEditField
        alphaMaxLabel                   matlab.ui.control.Label
        alpha                           matlab.ui.control.NumericEditField
        ParametroTabbyEvoTextArea       matlab.ui.control.TextArea
        ParametroTabbyEvoTextAreaLabel  matlab.ui.control.Label
        kappaEditFieldLabel             matlab.ui.control.Label
        kappa                           matlab.ui.control.NumericEditField
        PresionPsiSliderLabel           matlab.ui.control.Label
        Presion                         matlab.ui.control.Slider
        CamberLabel                     matlab.ui.control.Label
        camber                          matlab.ui.control.Slider
        BorrarButton                    matlab.ui.control.Button
        GraficarSpinnerLabel            matlab.ui.control.Label
        GraficarSpinner                 matlab.ui.control.Spinner
        UIAxes_8                        matlab.ui.control.UIAxes
        UIAxes_6                        matlab.ui.control.UIAxes
        UIAxes_4                        matlab.ui.control.UIAxes
        UIAxes_3                        matlab.ui.control.UIAxes
        UIAxes_2                        matlab.ui.control.UIAxes
        UIAxes                          matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: GraficarSpinner
        function GraficarButtonPushed(app, event)
global P_1
global G_1
global P_2
global G_2
            
            %Constantes
            nPoints = 200;
            
            %Variables de entrada
            alpha1	= linspace(-app.alpha.Value*pi()/180,app.alpha.Value*pi()/180, nPoints)';
            alpha2	= ones(nPoints,1).*app.alpha.Value.*pi()/180;
            Fz1 = ones(nPoints,1).*app.Fz.Value;
            kappa1 = ones(nPoints,1).*app.kappa.Value;
            kappa2 = linspace(-0.05,-1, nPoints)';
            gamma1 = ones(nPoints,1).*app.camber.Value.*(pi()/180);
            phit1 = ones(nPoints,1).*0;
            Vx1 = ones(nPoints,1).*app.Vx.Value.*(1000/3600);
            Vx2 = linspace(2,app.Vx.Value*(1000/3600), nPoints)';
            P1 = ones(nPoints,1).*app.Presion.Value.*6894.76;
            
            % Cree una cadena con el nombre del archivo TIR
            TIRfile1 = 'MagicFormula62_Parameters.TIR';
            
            % Seleccione un modo de uso
            useMode1 = 111;
            
            % Envuelva todas las entradas en una matriz
            inputs1 = [Fz1 kappa1 alpha1 gamma1 phit1 Vx1 P1];
            inputs2 = [Fz1 kappa1 alpha2 gamma1 phit1 Vx2 P1];
            inputs3 = [Fz1 kappa2 alpha2 gamma1 phit1 Vx1 P1];
            
            % Almacene la salida de mfeval en una matriz 2D
            output1 = mfeval(TIRfile1, inputs1, useMode1);
            output2 = mfeval(TIRfile1, inputs2, useMode1);
            output3 = mfeval(TIRfile1,inputs3, useMode1);
            
            % Extraiga variables de la salida MFeval. Para obtener más información, escriba "help mfeval"
            %Dinamica Lateral
            Fy1 = output1(:,2);
            Mx1 = output1(:,4);
            Mz1 = output1(:,6);
            SA1 = rad2deg(output1(:,8)); % Convertir a grados
            t1 = output1(:,16);
            %Dinamica Longitudinal
            Fx3 = output3(:,2);
            My2 = output2(:,5);
            VX2 = output2(:,11).*(3600/1000); % Convertir a km/h
            
            if app.GraficarSpinner.Value == 1
                %Graficas Dinamica lateral
                plot(app.UIAxes,SA1,Fy1);             
                plot(app.UIAxes_2,SA1,Mz1);                 
                plot(app.UIAxes_3,SA1,t1);
                plot(app.UIAxes_4,SA1,Mx1);
                P_1 = num2str(app.Presion.Value);
                G_1 = num2str(app.camber.Value);
                legend(app.UIAxes_4,['P=',P_1,'Psi y \gamma=',G_1,'º'],'Location','eastoutside')
                %Graficas dinamica longitudinal
                plot(app.UIAxes_8,VX2,-My2)
                plot(app.UIAxes_6,-100*kappa2,-Fx3)
            elseif app.GraficarSpinner.Value == 2
                %Graficas Dinamica lateral
                plot(app.UIAxes,SA1,Fy1);             
                plot(app.UIAxes_2,SA1,Mz1);                 
                plot(app.UIAxes_3,SA1,t1);
                plot(app.UIAxes_4,SA1,Mx1);
                P_2 = num2str(app.Presion.Value);
                G_2 = num2str(app.camber.Value);
                legend(app.UIAxes_4,{['P=',P_1,'Psi y \gamma=',G_1,'º'],['P=',P_2,'Psi y \gamma=',G_2,'º']},'Location','eastoutside')
                %Graficas dinamica longitudinal
                plot(app.UIAxes_8,VX2,-My2)
                plot(app.UIAxes_6,-100*kappa2,-Fx3)
            elseif app.GraficarSpinner.Value == 3
                %Graficas Dinamica lateral
                plot(app.UIAxes,SA1,Fy1);             
                plot(app.UIAxes_2,SA1,Mz1);                 
                plot(app.UIAxes_3,SA1,t1);
                plot(app.UIAxes_4,SA1,Mx1);
                P_3 = num2str(app.Presion.Value);
                G_3 = num2str(app.camber.Value);
                legend(app.UIAxes_4,{['P=',P_1,'Psi y \gamma=',G_1,'º'],['P=',P_2,'Psi y \gamma=',G_2,'º'],['P=',P_3,'Psi y \gamma=',G_3,'º']},'Location','eastoutside')
                %Graficas dinamica longitudinal
                plot(app.UIAxes_8,VX2,-My2)
                plot(app.UIAxes_6,-100*kappa2,-Fx3)
                
            end
            
 
        end

        % Button pushed function: BorrarButton
        function BorrarButtonPushed(app, event)
            cla(app.UIAxes)
            cla(app.UIAxes_2)
            cla(app.UIAxes_3)
            cla(app.UIAxes_4)
            cla(app.UIAxes_8)
            cla(app.UIAxes_6)
            app.GraficarSpinner.Value = 0;
            %W = 589.24*9.81; %Peso del vehículo
            %l1 = 1.0613; %Distancia al eje delantero desde el centro de masa [mm]
            %l2 = 1.2498;  %Distancia al eje trasero desde el centro de masa [mm]
            %fd = W*l2/(l1+l2); %Fuerza sobre el eje delantero [N]
            %ft = W*l1/(l1+l2); %Fuerza sobre el eje trasero [N]
            %F_zd = fd/2;     %Fuerza vertical en una llanta delantera [N]
            %F_zt = ft/2 ;    %Fuerza vertical en una llanta trasera [N]
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ComportamientodedinamicalongitudinalylateralUtilizandoMF62Label = uilabel(app.UIFigure);
            app.ComportamientodedinamicalongitudinalylateralUtilizandoMF62Label.Text = 'Comportamiento de dinamica longitudinal y lateral. Utilizando MF 6.2';
            app.FzNLabel = uilabel(app.UIFigure);
            app.FzNLabel.Text = 'Fz [N]';
            app.Fz = uieditfield(app.UIFigure, 'numeric');
            app.Fz.Value = 1563;
            app.VpromkmhLabel = uilabel(app.UIFigure);
            app.VpromkmhLabel.Text = 'Vprom [km/h]';
            app.Vx = uieditfield(app.UIFigure, 'numeric');
            app.Vx.Value = 50;
            app.alphaMaxLabel = uilabel(app.UIFigure);
            app.alphaMaxLabel.Text = 'alpha Max [º]';
            app.alpha = uieditfield(app.UIFigure, 'numeric');
            app.alpha.Value = 20;
            app.ParametroTabbyEvoTextArea = uitextarea(app.UIFigure);
            app.ParametroTabbyEvoTextArea.Value = {'Fzd = 1563 N'; 'Fzt  = 1327 N'};
            app.ParametroTabbyEvoTextAreaLabel = uilabel(app.UIFigure);
            app.ParametroTabbyEvoTextAreaLabel.Text = 'Parametro Tabby Evo';
            app.kappaEditFieldLabel = uilabel(app.UIFigure);
            app.kappaEditFieldLabel.Text = 'kappa';
            app.kappa = uieditfield(app.UIFigure, 'numeric');
            app.PresionPsiSliderLabel = uilabel(app.UIFigure);
            app.PresionPsiSliderLabel.Text = 'Presion [Psi]';
            app.Presion = uislider(app.UIFigure);
            app.Presion.Limits = [29 58];
            app.Presion.Value = 30;
            app.CamberLabel = uilabel(app.UIFigure);
            app.CamberLabel.Text = 'Camber [º]';
            app.camber = uislider(app.UIFigure);
            app.camber.Limits = [-5 5];
            app.BorrarButton = uibutton(app.UIFigure, 'push');
            app.BorrarButton.ButtonPushedFcn = createCallbackFcn(app, @BorrarButtonPushed, true);
            app.BorrarButton.Text = 'Borrar';
            app.GraficarSpinnerLabel = uilabel(app.UIFigure);
            app.GraficarSpinnerLabel.Text = 'Graficar';
            app.GraficarSpinner = uispinner(app.UIFigure);
            app.GraficarSpinner.Limits = [0 3];
            app.GraficarSpinner.ValueChangedFcn = createCallbackFcn(app, @GraficarButtonPushed, true);
            app.GraficarSpinner.Editable = 'off';
            app.GraficarSpinner.Tooltip = {'No disminuir contador, utilizar boton borrar'};
            app.UIAxes_8 = uiaxes(app.UIFigure);
            title(app.UIAxes_8, 'Momento de resistencia a la rodadura')
            xlabel(app.UIAxes_8, 'Vx [Km/h]')
            ylabel(app.UIAxes_8, '-My [Nm]')
            zlabel(app.UIAxes_8, 'Z')
            app.UIAxes_6 = uiaxes(app.UIFigure);
            title(app.UIAxes_6, '-Fx Vs kappa ')
            xlabel(app.UIAxes_6, 'Deslizamiento de frenado -k[%]')
            ylabel(app.UIAxes_6, 'Fuerza de frenado -Fx [N]')
            zlabel(app.UIAxes_6, 'Z')
            app.UIAxes_4 = uiaxes(app.UIFigure);
            title(app.UIAxes_4, 'Mx Vs \alpha')
            xlabel(app.UIAxes_4, 'Angulo de deslizamiento [º]')
            ylabel(app.UIAxes_4, 'Momento de vuelco [Nm]')
            zlabel(app.UIAxes_4, 'Z')
            app.UIAxes_3 = uiaxes(app.UIFigure);
            title(app.UIAxes_3, 't Vs \alpha')
            xlabel(app.UIAxes_3, 'Angulo de deslizamiento [º]')
            ylabel(app.UIAxes_3, 'huella neumatica [m]')
            zlabel(app.UIAxes_3, 'Z')
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Mz Vs \alpha')
            xlabel(app.UIAxes_2, 'Angulo de deslizamiento [º]')
            ylabel(app.UIAxes_2, 'Momento de autoalin. [Nm]')
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Fy Vs \alpha')
            xlabel(app.UIAxes, 'Angulo de deslizamiento [º]')
            ylabel(app.UIAxes, 'Fuerza lateral [N]')
            zlabel(app.UIAxes, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TiresMF

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end